classdef lemon_battery < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        mainscreenTab          matlab.ui.container.Tab
        voltageGauge           matlab.ui.control.Gauge
        voltageGaugeLabel      matlab.ui.control.Label
        InformationPanel       matlab.ui.container.Panel
        GoToQuizButton         matlab.ui.control.Button
        RandomTipFunFactLabel  matlab.ui.control.Label
        nexttipButton          matlab.ui.control.Button
        prevtipButton          matlab.ui.control.Button
        tip_text               matlab.ui.control.TextArea
        ControlsPanel          matlab.ui.container.Panel
        FruitDropDown          matlab.ui.control.DropDown
        FruitDropDownLabel     matlab.ui.control.Label
        RipenessSlider         matlab.ui.control.Slider
        RipenessSliderLabel    matlab.ui.control.Label
        outputLamp             matlab.ui.control.Lamp
        outputLampLabel        matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        quizscreenTab          matlab.ui.container.Tab
        question_text          matlab.ui.control.TextArea
        prevquestionButton     matlab.ui.control.Button
        nextquestionButton     matlab.ui.control.Button
        question_index_label   matlab.ui.control.Label
        AnswerButtonGroup      matlab.ui.container.ButtonGroup
        submitButton           matlab.ui.control.Button
        resultsscreenTab       matlab.ui.container.Tab
        learnsomemoreButton    matlab.ui.control.Button
        tryagainButton         matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
    end


    properties (Access = private)
        num_questions int32
        question_index int32  % keeps track of the current question
        questions string % list of the questions
        answers string % list of the options
        answer_buttons matlab.ui.control.RadioButton
        correct int32 % list of the correct answers
        scores int32 % how much each question is worth
        selections int32
        fact_timer timer % timer to control the swapping of facts
        fact_index int32
        facts string

    end

    methods (Access = private)

        function timer_callback(app)
            n_facts = length(app.facts);

            if app.fact_index == n_facts
                app.fact_index = 1;
            else
                app.fact_index = app.fact_index + 1;
            end

            app.tip_text.Value = app.facts(app.fact_index);

        end

        function set_question(app, index)
            app.question_text.Value = app.questions(index);

            % app.answerDropDown.ItemsData = app.answers(index,:);
            % app.answerDropDown.Items = {"test 1", "test 2", "test3"};
            [~,num_answers] = size(app.answers);
            app.answer_buttons;

            % [~,~, width, height] = app.AnswerButtonGroup.Position;

            height = 28;

            for i = 1:length(app.answer_buttons)
                delete(app.answer_buttons(i));
            end
 
            clear app.answer_buttons;

            for i = 1:num_answers

                button = uiradiobutton(app.AnswerButtonGroup);
                button.Text = app.answers(index, i);
                disp(app.answers(index,i));
                button.Position(2) = (height) * (i-1);
                disp(button.Position);

                app.answer_buttons(i) = button;

            end

            disp(app.answer_buttons);

            app.question_index_label.Text = sprintf("Question %d/%d", index, app.num_questions);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fact_index = 1;
            app.facts = load('facts.mat').facts;
            app.fact_timer = timer;
            app.fact_timer.TimerFcn = @(varargin)app.timer_callback;
            app.fact_timer.ExecutionMode = "fixedRate";
            app.fact_timer.Period=10;
            start(app.fact_timer);

            % quiz data loading

            app.questions = load("quiz.mat").questions;
            app.answers = load("quiz.mat").answers;
            app.correct = load("quiz.mat").correct;
            app.scores = load("quiz.mat").scores;

            app.question_index=1;
            app.num_questions = length(app.questions);

            app.set_question(1);


        end

        % Button pushed function: nextquestionButton
        function nextquestionButtonPushed(app, event)
            if app.question_index < app.num_questions
                app.prevquestionButton.Visible ="on";
                app.prevquestionButton.Enable = "on";


                app.question_index = app.question_index + 1;

                app.set_question(app.question_index);
            end

            if app.question_index == app.num_questions
                app.nextquestionButton.Visible ="off";
                app.nextquestionButton.Enable = "off";
            end

        end

        % Button pushed function: prevtipButton
        function prevtipButtonPushed(app, event)
            n_facts = length(app.facts);

            if app.fact_index == 1
                app.fact_index =n_facts;
            else
                app.fact_index = app.fact_index - 1;
            end

            app.tip_text.Value = app.facts(app.fact_index);


        end

        % Button pushed function: nexttipButton
        function nexttipButtonPushed(app, event)
            app.timer_callback();
        end

        % Value changed function: FruitDropDown
        function FruitDropDownValueChanged(app, event)
            value = app.FruitDropDown.Value;
            value = replace(value, " ", "");
            value = lower(value);

            path = append(value, ".png");

            app.Image.ImageSource = path;


        end

        % Button pushed function: GoToQuizButton
        function GoToQuizButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.quizscreenTab;
        end

        % Button pushed function: prevquestionButton
        function prevquestionButtonPushed(app, event)
            if app.question_index > 1
                app.nextquestionButton.Visible ="on";
                app.nextquestionButton.Enable = "on";


                app.question_index = app.question_index - 1;

                app.set_question(app.question_index);
            end

            if app.question_index == 1
                app.prevquestionButton.Visible ="off";
                app.prevquestionButton.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.mainscreenTab = uitab(app.TabGroup);
            app.mainscreenTab.Title = 'main screen';
            app.Image = uiimage(app.mainscreenTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'lemon.png');
            app.outputLampLabel = uilabel(app.mainscreenTab);
            app.outputLampLabel.Text = 'output';
            app.outputLamp = uilamp(app.mainscreenTab);
            app.ControlsPanel = uipanel(app.mainscreenTab);
            app.ControlsPanel.Title = 'Controls';
            app.RipenessSliderLabel = uilabel(app.ControlsPanel);
            app.RipenessSliderLabel.Text = 'Ripeness';
            app.RipenessSlider = uislider(app.ControlsPanel);
            app.FruitDropDownLabel = uilabel(app.ControlsPanel);
            app.FruitDropDownLabel.Text = 'Fruit';
            app.FruitDropDown = uidropdown(app.ControlsPanel);
            app.FruitDropDown.Items = {'Lemon', 'Lime', 'Grapefruit', 'Orange', 'Potato'};
            app.FruitDropDown.ValueChangedFcn = createCallbackFcn(app, @FruitDropDownValueChanged, true);
            app.FruitDropDown.Value = 'Lemon';
            app.InformationPanel = uipanel(app.mainscreenTab);
            app.InformationPanel.Title = 'Information';
            app.tip_text = uitextarea(app.InformationPanel);
            app.tip_text.Editable = 'off';
            app.tip_text.Value = {'you can get different voltages by changing the type of fruit, as well as the ripeness, etc.'};
            app.prevtipButton = uibutton(app.InformationPanel, 'push');
            app.prevtipButton.ButtonPushedFcn = createCallbackFcn(app, @prevtipButtonPushed, true);
            app.prevtipButton.Text = '←     prev tip';
            app.nexttipButton = uibutton(app.InformationPanel, 'push');
            app.nexttipButton.ButtonPushedFcn = createCallbackFcn(app, @nexttipButtonPushed, true);
            app.nexttipButton.Text = 'next tip     →';
            app.RandomTipFunFactLabel = uilabel(app.InformationPanel);
            app.RandomTipFunFactLabel.Text = 'Random Tip / Fun Fact';
            app.GoToQuizButton = uibutton(app.InformationPanel, 'push');
            app.GoToQuizButton.ButtonPushedFcn = createCallbackFcn(app, @GoToQuizButtonPushed, true);
            app.GoToQuizButton.Text = 'Go To Quiz';
            app.voltageGaugeLabel = uilabel(app.mainscreenTab);
            app.voltageGaugeLabel.Text = 'voltage';
            app.voltageGauge = uigauge(app.mainscreenTab, 'circular');
            app.voltageGauge.Limits = [0 1];
            app.quizscreenTab = uitab(app.TabGroup);
            app.quizscreenTab.Title = 'quiz screen';
            app.submitButton = uibutton(app.quizscreenTab, 'push');
            app.submitButton.Text = 'submit';
            app.AnswerButtonGroup = uibuttongroup(app.quizscreenTab);
            app.AnswerButtonGroup.Title = 'Answer';
            app.question_index_label = uilabel(app.quizscreenTab);
            app.question_index_label.Text = 'question n/?';
            app.nextquestionButton = uibutton(app.quizscreenTab, 'push');
            app.nextquestionButton.ButtonPushedFcn = createCallbackFcn(app, @nextquestionButtonPushed, true);
            app.nextquestionButton.Text = 'next question';
            app.prevquestionButton = uibutton(app.quizscreenTab, 'push');
            app.prevquestionButton.ButtonPushedFcn = createCallbackFcn(app, @prevquestionButtonPushed, true);
            app.prevquestionButton.Text = 'prev question';
            app.question_text = uitextarea(app.quizscreenTab);
            app.question_text.Editable = 'off';
            app.question_text.Value = {'question text'};
            app.resultsscreenTab = uitab(app.TabGroup);
            app.resultsscreenTab.Title = 'results screen';
            app.TextArea = uitextarea(app.resultsscreenTab);
            app.TextArea.Value = {'you scored: ?/?!'};
            app.tryagainButton = uibutton(app.resultsscreenTab, 'push');
            app.tryagainButton.Text = 'try again';
            app.learnsomemoreButton = uibutton(app.resultsscreenTab, 'push');
            app.learnsomemoreButton.Text = 'learn some more';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lemon_battery

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end